classdef sew < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ROSPanel                   matlab.ui.container.Panel
        STOPButton                 matlab.ui.control.StateButton
        ROSConnectionSwitch        matlab.ui.control.Switch
        ROSConnectionSwitchLabel   matlab.ui.control.Label
        ConnectedLamp              matlab.ui.control.Lamp
        ConnectedLampLabel         matlab.ui.control.Label
        RobotTypeButtonGroup       matlab.ui.container.ButtonGroup
        JointLimitsSwitch          matlab.ui.control.Switch
        JointLimitsSwitchLabel     matlab.ui.control.Label
        Sawyer0SphericalButton     matlab.ui.control.ToggleButton
        Spacebot1SphericalButton   matlab.ui.control.ToggleButton
        Anthropomorphic2SphericalButton  matlab.ui.control.ToggleButton
        WarningsPanel              matlab.ui.container.Panel
        JointLimitLamp             matlab.ui.control.Lamp
        JointLimitLampLabel        matlab.ui.control.Label
        SingularityLamp            matlab.ui.control.Lamp
        SingularityLampLabel       matlab.ui.control.Label
        SEWAnglePanel              matlab.ui.container.Panel
        StereographicSpinner       matlab.ui.control.Spinner
        StereographicSpinnerLabel  matlab.ui.control.Label
        ConventionalSpinner        matlab.ui.control.Spinner
        ConventionalSpinnerLabel   matlab.ui.control.Label
        ControlMethodButtonGroup   matlab.ui.container.ButtonGroup
        LSConstStereoButton        matlab.ui.control.ToggleButton
        LSConstConvButton          matlab.ui.control.ToggleButton
        ConstStereoSEWButton       matlab.ui.control.ToggleButton
        ConstConvSEWButton         matlab.ui.control.ToggleButton
        LeastSquaresButton         matlab.ui.control.ToggleButton
        EEOrientationPanel         matlab.ui.container.Panel
        R_YSpinner                 matlab.ui.control.Spinner
        R_YSpinnerLabel            matlab.ui.control.Label
        R_XSpinner                 matlab.ui.control.Spinner
        R_XSpinnerLabel            matlab.ui.control.Label
        R_ZSpinner                 matlab.ui.control.Spinner
        R_ZLabel                   matlab.ui.control.Label
        q_yEditField               matlab.ui.control.NumericEditField
        q_yEditFieldLabel          matlab.ui.control.Label
        q_zEditField               matlab.ui.control.NumericEditField
        q_zEditFieldLabel          matlab.ui.control.Label
        q_wEditField               matlab.ui.control.NumericEditField
        q_wEditFieldLabel          matlab.ui.control.Label
        q_xEditField               matlab.ui.control.NumericEditField
        q_xEditFieldLabel          matlab.ui.control.Label
        EEPositionPanel            matlab.ui.container.Panel
        ZSpinner                   matlab.ui.control.Spinner
        ZSpinnerLabel              matlab.ui.control.Label
        YSpinner                   matlab.ui.control.Spinner
        YSpinnerLabel              matlab.ui.control.Label
        XSpinner                   matlab.ui.control.Spinner
        XSpinnerLabel              matlab.ui.control.Label
        JointAnglesPanel           matlab.ui.container.Panel
        q_7Spinner                 matlab.ui.control.Spinner
        q_7SpinnerLabel            matlab.ui.control.Label
        q_6Spinner                 matlab.ui.control.Spinner
        q_6SpinnerLabel            matlab.ui.control.Label
        q_5Spinner                 matlab.ui.control.Spinner
        q_5SpinnerLabel            matlab.ui.control.Label
        q_4Spinner                 matlab.ui.control.Spinner
        q_4SpinnerLabel            matlab.ui.control.Label
        q_3Spinner                 matlab.ui.control.Spinner
        q_3SpinnerLabel            matlab.ui.control.Label
        q_2Spinner                 matlab.ui.control.Spinner
        q_2SpinnerLabel            matlab.ui.control.Label
        q_1Spinner                 matlab.ui.control.Spinner
        q_1SpinnerLabel            matlab.ui.control.Label
        ConfigurationControlParametersPanel  matlab.ui.container.Panel
        GC_6ButtonGroup            matlab.ui.container.ButtonGroup
        Button_GC_6_minus          matlab.ui.control.ToggleButton
        Button_GC_6_plus           matlab.ui.control.ToggleButton
        GC_4ButtonGroup            matlab.ui.container.ButtonGroup
        Button_GC_4_minus          matlab.ui.control.ToggleButton
        Button_GC_4_plus           matlab.ui.control.ToggleButton
        GC_2ButtonGroup            matlab.ui.container.ButtonGroup
        Button_GC_2_minus          matlab.ui.control.ToggleButton
        Button_GC_2_plus           matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        robot_sim
        prev_R_spinner_vals
        sawyer_ros_com
    end
    
    methods (Access = private)
        function q_spinner_change(app, event, i)
            if app.ROSConnectionSwitch.Value == "On"
                app.sawyer_ros_com.send_position_cmd(app.robot_sim.q);
            end

            changingValue = event.Value;
            q_next = app.robot_sim.q;
            q_next(i) = deg2rad(changingValue);
            app.robot_sim.set_q(q_next)

            app.fwd_kin()
            app.display_task();
            app.robot_sim.show_plot();
        end

        function xyz_change(app, event, i)
            changingValue = event.Value;
            xyz = app.robot_sim.xyz;
            xyz(i) = changingValue;
            
            [is_ls, SEW_type, psi] = app.get_ls_SEW_type();
            if is_ls
                app.robot_sim.inv_kin_ls(app.robot_sim.quat, xyz, SEW_type, psi);


                app.display_sew_by_changed("Conventional")
                app.display_sew_by_changed("Stereographic")
            else
                [inv_kin_success, fail_reason] = app.robot_sim.inv_kin(app.robot_sim.quat, xyz, SEW_type, psi, app.robot_sim.GC, true, app.JointLimitsSwitch.Value=="On");
                if ~inv_kin_success
                    if fail_reason == "singularity"
                        app.SingularityLamp.Color = [1,0,0];
                    elseif fail_reason == "joint_limit"
                        app.JointLimitLamp.Color = [1,0,0];
                    end
                    app.display_task();
                else
                    app.SingularityLamp.Color = [0.2,0,0];
                    app.JointLimitLamp.Color = [0.2,0,0];
                end
                app.display_sew_by_changed(SEW_type)
            end

            app.display_q();
            
            app.robot_sim.show_plot();
        end

        function R_spinner_change(app, event, i)
            changingValue = event.Value;
            dir = sign(changingValue - app.prev_R_spinner_vals(i));
            app.prev_R_spinner_vals(i) = changingValue;
    
            axang = [0 0 0 dir];
            axang(i) = 1;
            app.quat_change(axang);
        end

        function quat_change(app,axang)
            axang(4) = deg2rad(axang(4));
            rotm = axang2rotm(axang);
            R = rotm*quat2rotm(app.robot_sim.quat);
            
            [is_ls, SEW_type, psi] = app.get_ls_SEW_type();

            if is_ls
                app.robot_sim.inv_kin_ls(rotm2quat(R), app.robot_sim.xyz, SEW_type, psi, true)
                app.display_sew_by_changed("Conventional")
                app.display_sew_by_changed("Stereographic")
            else
                app.robot_sim.inv_kin(rotm2quat(R), app.robot_sim.xyz, SEW_type, psi, app.robot_sim.GC, true)
                app.display_sew_by_changed(SEW_type)
            end

            app.display_q();
            
            app.q_wEditField.Value = app.robot_sim.quat(1);
            app.q_xEditField.Value = app.robot_sim.quat(2);
            app.q_yEditField.Value = app.robot_sim.quat(3);
            app.q_zEditField.Value = app.robot_sim.quat(4);
            
            app.robot_sim.show_plot();
        
        end

        function GC_change(app, is_plus, i)
            GC = app.robot_sim.GC;
            if is_plus
                GC(i) = 1;
            else
                GC(i) = -1;
            end

            app.robot_sim.inv_kin(app.robot_sim.quat, app.robot_sim.xyz, "Conventional", app.robot_sim.sew_conv, GC, false)

            app.display_q();
            app.robot_sim.show_plot();
        end

        function SEW_change(app, event, SEW_type)
            psi = deg2rad(event.Value);
            app.robot_sim.inv_kin(app.robot_sim.quat, app.robot_sim.xyz, SEW_type, psi, app.robot_sim.GC, true)
        
            app.display_q();
            
            app.display_sew_by_changed(SEW_type)

            app.robot_sim.show_plot();
        end

        function display_sew_by_changed(app, changed_SEW_type)
            if changed_SEW_type == "Conventional" % Display the OTHER angle
                app.StereographicSpinner.Value = rad2deg(app.robot_sim.sew_stereo);
            else
                app.ConventionalSpinner.Value = rad2deg(app.robot_sim.sew_conv);
            end
        end


        function [is_ls, SEW_type, psi] = get_ls_SEW_type(app)
            is_ls = app.LeastSquaresButton.Value || app.LSConstConvButton.Value || app.LSConstStereoButton.Value;

            if app.ConstConvSEWButton.Value || app.LSConstConvButton.Value
                SEW_type = "Conventional";
                psi = app.robot_sim.sew_conv;
            elseif app.ConstStereoSEWButton.Value || app.LSConstStereoButton.Value
                SEW_type = "Stereographic";
                psi = app.robot_sim.sew_stereo;
            else
                SEW_type = "None";
                psi = NaN;
            end
        end

        function fwd_kin(app)
            app.robot_sim.fwd_kin();
            app.robot_sim.fwd_kin_sew();
        end

        function display_q(app)
                if app.ROSConnectionSwitch.Value == "On"
                    app.sawyer_ros_com.send_position_cmd(app.robot_sim.q);
                end


                app.q_1Spinner.Value = rad2deg(app.robot_sim.q(1));
                app.q_2Spinner.Value = rad2deg(app.robot_sim.q(2));
                app.q_3Spinner.Value = rad2deg(app.robot_sim.q(3));
                app.q_4Spinner.Value = rad2deg(app.robot_sim.q(4));
                app.q_5Spinner.Value = rad2deg(app.robot_sim.q(5));
                app.q_6Spinner.Value = rad2deg(app.robot_sim.q(6));
                app.q_7Spinner.Value = rad2deg(app.robot_sim.q(7));
        end

        function display_task(app)
            app.XSpinner.Value = app.robot_sim.xyz(1);
            app.YSpinner.Value = app.robot_sim.xyz(2);
            app.ZSpinner.Value = app.robot_sim.xyz(3);

            app.q_wEditField.Value = app.robot_sim.quat(1);
            app.q_xEditField.Value = app.robot_sim.quat(2);
            app.q_yEditField.Value = app.robot_sim.quat(3);
            app.q_zEditField.Value = app.robot_sim.quat(4);

            app.ConventionalSpinner.Value = rad2deg(app.robot_sim.sew_conv);
            app.StereographicSpinner.Value = rad2deg(app.robot_sim.sew_stereo);
            
            if app.robot_sim.GC(1) == 1
                app.Button_GC_2_plus.Value = true;
            else
                app.Button_GC_2_minus.Value = true;
            end

            if app.robot_sim.GC(2) == 1
                app.Button_GC_4_plus.Value = true;
            else
                app.Button_GC_4_minus.Value = true;
            end

            if app.robot_sim.GC(3) == 1
                app.Button_GC_6_plus.Value = true;
            else
                app.Button_GC_6_minus.Value = true;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('general-robotics-toolbox-master\')

            app.robot_sim = robot_kinematic_sim_kuka();


            app.display_q()
            app.display_task()
            app.robot_sim.show_plot();

            app.prev_R_spinner_vals = [0 0 0];
        end

        % Value changing function: q_1Spinner
        function q_1SpinnerValueChanging(app, event)
            app.q_spinner_change(event, 1)
        end

        % Value changing function: q_2Spinner
        function q_2SpinnerValueChanging(app, event)
            app.q_spinner_change(event, 2)
        end

        % Value changing function: q_3Spinner
        function q_3SpinnerValueChanging(app, event)
            app.q_spinner_change(event, 3)
        end

        % Value changing function: q_4Spinner
        function q_4SpinnerValueChanging(app, event)
            app.q_spinner_change(event, 4)
        end

        % Value changing function: q_5Spinner
        function q_5SpinnerValueChanging(app, event)
            app.q_spinner_change(event, 5)
        end

        % Value changing function: q_6Spinner
        function q_6SpinnerValueChanging(app, event)
            app.q_spinner_change(event, 6)
        end

        % Value changing function: q_7Spinner
        function q_7SpinnerValueChanging(app, event)
            app.q_spinner_change(event, 7)
        end

        % Selection changed function: RobotTypeButtonGroup
        function RobotTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.RobotTypeButtonGroup.SelectedObject;
            disp(selectedButton)
            disp(selectedButton.Text)
            if selectedButton.Text == "Anthropomorphic (2 Spherical)"
                app.robot_sim = robot_kinematic_sim_kuka();
            elseif selectedButton.Text == "Spacebot (1 Spherical)"
                app.robot_sim = robot_kinematic_sim_spacebot();
            elseif selectedButton.Text == "Sawyer (0 Spherical)"
                app.robot_sim = robot_kinematic_sim_sawyer();
            end
        end

        % Value changing function: XSpinner
        function XSpinnerValueChanging(app, event)
            app.xyz_change(event,1)
        end

        % Value changing function: YSpinner
        function YSpinnerValueChanging(app, event)
            app.xyz_change(event,2)
        end

        % Value changing function: ZSpinner
        function ZSpinnerValueChanging(app, event)
            app.xyz_change(event,3)
        end

        % Selection changed function: GC_2ButtonGroup
        function GC_2ButtonGroupSelectionChanged(app, event)
            app.GC_change(app.Button_GC_2_plus.Value, 1)            
        end

        % Selection changed function: GC_4ButtonGroup
        function GC_4ButtonGroupSelectionChanged(app, event)
            app.GC_change(app.Button_GC_4_plus.Value, 2)            
        end

        % Selection changed function: GC_6ButtonGroup
        function GC_6ButtonGroupSelectionChanged(app, event)
            app.GC_change(app.Button_GC_6_plus.Value, 3)          
        end

        % Value changing function: ConventionalSpinner
        function ConventionalSpinnerValueChanging(app, event)
            app.SEW_change(event, "Conventional")
        end

        % Value changing function: StereographicSpinner
        function StereographicSpinnerValueChanging(app, event)
            app.SEW_change(event, "Stereographic")
        end

        % Value changing function: R_XSpinner
        function R_XSpinnerValueChanging(app, event)
            app.R_spinner_change(event, 1)
        end

        % Value changing function: R_YSpinner
        function R_YSpinnerValueChanging(app, event)
            app.R_spinner_change(event, 2)
        end

        % Value changing function: R_ZSpinner
        function R_ZSpinnerValueChanging(app, event)
            app.R_spinner_change(event, 3)
        end

        % Value changed function: ROSConnectionSwitch
        function ROSConnectionSwitchValueChanged(app, event)
            value = app.ROSConnectionSwitch.Value;
            if value == "On"
                addpath("./ros/")
                app.sawyer_ros_com = sawyer_ros();
                app.ConnectedLamp.Color = [0.00,1.00,0.00];
            else
                clear app.sawyer_ros_com
                app.ConnectedLamp.Color = [0.00,0.20,0.00];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfigurationControlParametersPanel = uipanel(app.UIFigure);
            app.ConfigurationControlParametersPanel.Title = 'Configuration Control Parameters';
            app.GC_2ButtonGroup = uibuttongroup(app.ConfigurationControlParametersPanel);
            app.GC_2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GC_2ButtonGroupSelectionChanged, true);
            app.GC_2ButtonGroup.Title = 'GC_2';
            app.Button_GC_2_plus = uitogglebutton(app.GC_2ButtonGroup);
            app.Button_GC_2_plus.Text = '+1';
            app.Button_GC_2_plus.Value = true;
            app.Button_GC_2_minus = uitogglebutton(app.GC_2ButtonGroup);
            app.Button_GC_2_minus.Text = '-1';
            app.GC_4ButtonGroup = uibuttongroup(app.ConfigurationControlParametersPanel);
            app.GC_4ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GC_4ButtonGroupSelectionChanged, true);
            app.GC_4ButtonGroup.Title = 'GC_4';
            app.Button_GC_4_plus = uitogglebutton(app.GC_4ButtonGroup);
            app.Button_GC_4_plus.Text = '+1';
            app.Button_GC_4_plus.Value = true;
            app.Button_GC_4_minus = uitogglebutton(app.GC_4ButtonGroup);
            app.Button_GC_4_minus.Text = '-1';
            app.GC_6ButtonGroup = uibuttongroup(app.ConfigurationControlParametersPanel);
            app.GC_6ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GC_6ButtonGroupSelectionChanged, true);
            app.GC_6ButtonGroup.Title = 'GC_6';
            app.Button_GC_6_plus = uitogglebutton(app.GC_6ButtonGroup);
            app.Button_GC_6_plus.Text = '+1';
            app.Button_GC_6_plus.Value = true;
            app.Button_GC_6_minus = uitogglebutton(app.GC_6ButtonGroup);
            app.Button_GC_6_minus.Text = '-1';
            app.JointAnglesPanel = uipanel(app.UIFigure);
            app.JointAnglesPanel.Title = 'Joint Angles';
            app.q_1SpinnerLabel = uilabel(app.JointAnglesPanel);
            app.q_1SpinnerLabel.Text = 'q_1';
            app.q_1Spinner = uispinner(app.JointAnglesPanel);
            app.q_1Spinner.ValueChangingFcn = createCallbackFcn(app, @q_1SpinnerValueChanging, true);
            app.q_2SpinnerLabel = uilabel(app.JointAnglesPanel);
            app.q_2SpinnerLabel.Text = 'q_2';
            app.q_2Spinner = uispinner(app.JointAnglesPanel);
            app.q_2Spinner.ValueChangingFcn = createCallbackFcn(app, @q_2SpinnerValueChanging, true);
            app.q_3SpinnerLabel = uilabel(app.JointAnglesPanel);
            app.q_3SpinnerLabel.Text = 'q_3';
            app.q_3Spinner = uispinner(app.JointAnglesPanel);
            app.q_3Spinner.ValueChangingFcn = createCallbackFcn(app, @q_3SpinnerValueChanging, true);
            app.q_4SpinnerLabel = uilabel(app.JointAnglesPanel);
            app.q_4SpinnerLabel.Text = 'q_4';
            app.q_4Spinner = uispinner(app.JointAnglesPanel);
            app.q_4Spinner.ValueChangingFcn = createCallbackFcn(app, @q_4SpinnerValueChanging, true);
            app.q_5SpinnerLabel = uilabel(app.JointAnglesPanel);
            app.q_5SpinnerLabel.Text = 'q_5';
            app.q_5Spinner = uispinner(app.JointAnglesPanel);
            app.q_5Spinner.ValueChangingFcn = createCallbackFcn(app, @q_5SpinnerValueChanging, true);
            app.q_6SpinnerLabel = uilabel(app.JointAnglesPanel);
            app.q_6SpinnerLabel.Text = 'q_6';
            app.q_6Spinner = uispinner(app.JointAnglesPanel);
            app.q_6Spinner.ValueChangingFcn = createCallbackFcn(app, @q_6SpinnerValueChanging, true);
            app.q_7SpinnerLabel = uilabel(app.JointAnglesPanel);
            app.q_7SpinnerLabel.Text = 'q_7';
            app.q_7Spinner = uispinner(app.JointAnglesPanel);
            app.q_7Spinner.ValueChangingFcn = createCallbackFcn(app, @q_7SpinnerValueChanging, true);
            app.EEPositionPanel = uipanel(app.UIFigure);
            app.EEPositionPanel.Title = 'EE Position';
            app.XSpinnerLabel = uilabel(app.EEPositionPanel);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.EEPositionPanel);
            app.XSpinner.Step = 0.01;
            app.XSpinner.ValueChangingFcn = createCallbackFcn(app, @XSpinnerValueChanging, true);
            app.YSpinnerLabel = uilabel(app.EEPositionPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.EEPositionPanel);
            app.YSpinner.Step = 0.01;
            app.YSpinner.ValueChangingFcn = createCallbackFcn(app, @YSpinnerValueChanging, true);
            app.ZSpinnerLabel = uilabel(app.EEPositionPanel);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.EEPositionPanel);
            app.ZSpinner.Step = 0.01;
            app.ZSpinner.ValueChangingFcn = createCallbackFcn(app, @ZSpinnerValueChanging, true);
            app.EEOrientationPanel = uipanel(app.UIFigure);
            app.EEOrientationPanel.Title = 'EE Orientation';
            app.q_xEditFieldLabel = uilabel(app.EEOrientationPanel);
            app.q_xEditFieldLabel.Text = 'q_x';
            app.q_xEditField = uieditfield(app.EEOrientationPanel, 'numeric');
            app.q_wEditFieldLabel = uilabel(app.EEOrientationPanel);
            app.q_wEditFieldLabel.Text = 'q_w';
            app.q_wEditField = uieditfield(app.EEOrientationPanel, 'numeric');
            app.q_zEditFieldLabel = uilabel(app.EEOrientationPanel);
            app.q_zEditFieldLabel.Text = 'q_z';
            app.q_zEditField = uieditfield(app.EEOrientationPanel, 'numeric');
            app.q_yEditFieldLabel = uilabel(app.EEOrientationPanel);
            app.q_yEditFieldLabel.Text = 'q_y';
            app.q_yEditField = uieditfield(app.EEOrientationPanel, 'numeric');
            app.R_ZLabel = uilabel(app.EEOrientationPanel);
            app.R_ZLabel.Text = 'R_Z';
            app.R_ZSpinner = uispinner(app.EEOrientationPanel);
            app.R_ZSpinner.ValueChangingFcn = createCallbackFcn(app, @R_ZSpinnerValueChanging, true);
            app.R_XSpinnerLabel = uilabel(app.EEOrientationPanel);
            app.R_XSpinnerLabel.Text = 'R_X';
            app.R_XSpinner = uispinner(app.EEOrientationPanel);
            app.R_XSpinner.ValueChangingFcn = createCallbackFcn(app, @R_XSpinnerValueChanging, true);
            app.R_YSpinnerLabel = uilabel(app.EEOrientationPanel);
            app.R_YSpinnerLabel.Text = 'R_Y';
            app.R_YSpinner = uispinner(app.EEOrientationPanel);
            app.R_YSpinner.ValueChangingFcn = createCallbackFcn(app, @R_YSpinnerValueChanging, true);
            app.ControlMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.ControlMethodButtonGroup.Title = 'Control Method';
            app.LeastSquaresButton = uitogglebutton(app.ControlMethodButtonGroup);
            app.LeastSquaresButton.Text = 'Least Squares';
            app.ConstConvSEWButton = uitogglebutton(app.ControlMethodButtonGroup);
            app.ConstConvSEWButton.Text = 'Const Conv SEW';
            app.ConstConvSEWButton.Value = true;
            app.ConstStereoSEWButton = uitogglebutton(app.ControlMethodButtonGroup);
            app.ConstStereoSEWButton.Text = 'Const Stereo SEW';
            app.LSConstConvButton = uitogglebutton(app.ControlMethodButtonGroup);
            app.LSConstConvButton.Text = 'LS + Const Conv';
            app.LSConstStereoButton = uitogglebutton(app.ControlMethodButtonGroup);
            app.LSConstStereoButton.Text = 'LS + Const Stereo';
            app.SEWAnglePanel = uipanel(app.UIFigure);
            app.SEWAnglePanel.Title = 'SEW Angle';
            app.ConventionalSpinnerLabel = uilabel(app.SEWAnglePanel);
            app.ConventionalSpinnerLabel.Text = 'Conventional';
            app.ConventionalSpinner = uispinner(app.SEWAnglePanel);
            app.ConventionalSpinner.Step = 10;
            app.ConventionalSpinner.ValueChangingFcn = createCallbackFcn(app, @ConventionalSpinnerValueChanging, true);
            app.StereographicSpinnerLabel = uilabel(app.SEWAnglePanel);
            app.StereographicSpinnerLabel.Text = 'Stereographic';
            app.StereographicSpinner = uispinner(app.SEWAnglePanel);
            app.StereographicSpinner.Step = 10;
            app.StereographicSpinner.ValueChangingFcn = createCallbackFcn(app, @StereographicSpinnerValueChanging, true);
            app.WarningsPanel = uipanel(app.UIFigure);
            app.WarningsPanel.Title = 'Warnings';
            app.SingularityLampLabel = uilabel(app.WarningsPanel);
            app.SingularityLampLabel.Text = 'Singularity';
            app.SingularityLamp = uilamp(app.WarningsPanel);
            app.JointLimitLampLabel = uilabel(app.WarningsPanel);
            app.JointLimitLampLabel.Text = 'Joint Limit';
            app.JointLimitLamp = uilamp(app.WarningsPanel);
            app.RobotTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.RobotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RobotTypeButtonGroupSelectionChanged, true);
            app.RobotTypeButtonGroup.Title = 'Robot Type';
            app.Anthropomorphic2SphericalButton = uitogglebutton(app.RobotTypeButtonGroup);
            app.Anthropomorphic2SphericalButton.Text = 'Anthropomorphic (2 Spherical)';
            app.Anthropomorphic2SphericalButton.Value = true;
            app.Spacebot1SphericalButton = uitogglebutton(app.RobotTypeButtonGroup);
            app.Spacebot1SphericalButton.Text = 'Spacebot (1 Spherical)';
            app.Sawyer0SphericalButton = uitogglebutton(app.RobotTypeButtonGroup);
            app.Sawyer0SphericalButton.Text = 'Sawyer (0 Spherical)';
            app.JointLimitsSwitchLabel = uilabel(app.RobotTypeButtonGroup);
            app.JointLimitsSwitchLabel.Text = 'Joint Limits';
            app.JointLimitsSwitch = uiswitch(app.RobotTypeButtonGroup, 'slider');
            app.ROSPanel = uipanel(app.UIFigure);
            app.ROSPanel.Title = 'ROS';
            app.ConnectedLampLabel = uilabel(app.ROSPanel);
            app.ConnectedLampLabel.Text = 'Connected';
            app.ConnectedLamp = uilamp(app.ROSPanel);
            app.ROSConnectionSwitchLabel = uilabel(app.ROSPanel);
            app.ROSConnectionSwitchLabel.Text = 'ROS Connection';
            app.ROSConnectionSwitch = uiswitch(app.ROSPanel, 'slider');
            app.ROSConnectionSwitch.ValueChangedFcn = createCallbackFcn(app, @ROSConnectionSwitchValueChanged, true);
            app.STOPButton = uibutton(app.ROSPanel, 'state');
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sew

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end